classdef FnsOfRV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnterWFunctionofYEditField      matlab.ui.control.EditField
        EnterWFunctionofYEditFieldLabel  matlab.ui.control.Label
        EnterZFunctionofXEditField      matlab.ui.control.EditField
        EnterZFunctionofXEditFieldLabel  matlab.ui.control.Label
        FunctionsOfRandomVariableLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Corr                            matlab.ui.control.Label
        Covar                           matlab.ui.control.Label
        XStd                            matlab.ui.control.Label
        YStd                            matlab.ui.control.Label
        SingleStatisticsButton          matlab.ui.control.Button
        Ymean                           matlab.ui.control.Label
        Xmean                           matlab.ui.control.Label
        JointStatisticsButton           matlab.ui.control.Button
        EnterFilenameEditField          matlab.ui.control.EditField
        EnterFileNameLabel              matlab.ui.control.Label
    end


    methods (Access = private)
    function PlotPDF(app,X)
        if isempty(X)
            msgbox('Invalid input for PDF plot');
            return;
        end
        unique_values=unique(X);
        if(size(unique_values)/size(X)<0.75)
            % Calculate PMF
             [pdf_counts, pdf_edges] = histcounts(X,'Binwidth',0.01,'Normalization', 'probability');
             pdf_x = pdf_edges(1:end-1); % PDF x values
    
                % Create a new figure for the PDF
             histogram('BinEdges',pdf_edges,'BinCounts',pdf_counts,'Normalization','probability');
             xlabel('Value');
             ylabel('Probability');
             title('Probability Mass Function (PMF)');
        end
        if(size(unique_values)/size(X)>0.75)
             % Calculate PDF
             [pdf_counts, pdf_edges] = histcounts(X,'Binwidth',0.01,'Normalization', 'pdf');
             pdf_x = pdf_edges(1:end-1); % PDF x values
    
             % Create a new figure for the PDF
             histogram('BinEdges',pdf_edges,'BinCounts',pdf_counts,'Normalization','pdf');
             xlabel('Value');
             ylabel('Probability');
             title('Probability Density Function (PDF)');
       end
    end
    
    % Button pushed function: PlotButton
    function CalculateJointStatistics(app)
        fileName = app.EnterFilenameEditField.Value;
        if isempty(fileName)
            uialert(app.UIFigure, 'Please enter a file name.', 'Error');
            return;
        end
        
        % Try loading the .mat file and plotting
        try
            % Load the .mat file
            data = load(fileName);
            
            % Ensure the file contains a 2D array
            fieldNames = fieldnames(data);
            if numel(fieldNames) ~= 1
                uialert(app.UIFigure, 'The .mat file must contain exactly one 2D array.', 'Error');
                return;
            end
            
            % Extract the 2D array
            array = data.(fieldNames{1});
            if size(array, 1) ~= 2
                uialert(app.UIFigure, 'The 2D array must have exactly two rows: one for X and one for Y.', 'Error');
                return;
            end

            % Separate X and Y
            X = array(1, :);
            Y = array(2, :);
                
             % Get Z fn of X
            funcz=app.EnterZFunctionofXEditField.Value;
            algebraicFuncz=generateAlgebraicFunction(app,funcz,'X');
            Z=algebraicFuncz(X);

            % Get W fn of Y
            funcw=app.EnterWFunctionofYEditField.Value;
            algebraicFuncw = generateAlgebraicFunction(app,funcw,'Y'); % Convert the string to a symbolic expression
            W=algebraicFuncw(Y);
            
            % Compute and plot the joint PDF
            [N_pdf,Zedges_pdf,Wedges_pdf]=histcounts2(Z,W,'Normalization','pdf','BinWidth',0.1);
            [N_cdf,Zedges_cdf,Wedges_cdf]=histcounts2(Z,W,'Normalization','cdf');
            figure()
            histogram2('XBinEdges', Zedges_pdf, 'YBinEdges', Wedges_pdf, 'BinCounts', N_pdf);
            title("Joint Probability Distribution");
            xlabel('Z');
            ylabel('W');
            zlabel('PDF');
            
            %Mean
            mean_z=0;
            mean_w=0;
            for i=1 : length(Z)
                mean_z=mean_z+Z(i);
            end
            mean_z=mean_z/(length(Z))
            
            for i=1 : length(W)
                mean_w=mean_w+W(i);
            end
            mean_w=mean_w/length(W)
            
            %Covariance
            covar=0;
            for i=1 : length(Z)
                cz=(Z(i)-mean_z);
                cw=(W(i)-mean_w);
                covar=covar+cz*cw;
            end
            covar=covar/length(Z)
            
            %STD
            std_z=0;
            std_w=0;
            for i=1:length(Z)
                std_z=std_z+(Z(i)-mean_z)^2;
            end
            std_z=std_z/length(Z);
            std_z=sqrt(std_z)
            
            for i=1:length(W)
                std_w=std_w+(W(i)-mean_w)^2;
            end
            std_w=std_w/length(W);
            std_w=sqrt(std_w)
            
            %Correlation
            correlation_coeff=covar/(std_z*std_w)
            app.Covar.Text=['Covariance: ',num2str(covar,3)];
            app.Corr.Text=['Correlation Coeffecient: ',num2str(correlation_coeff,3)];
        catch ME
            % Show an error dialog if anything goes wrong
            uialert(app.UIFigure, ['Error: ' ME.message], 'Error');
        end
    end
       
    function CalculateSingleStatistics(app)
        % Get file name from the edit field
        fileName = app.EnterFilenameEditField.Value;
      
        % Validate the file name
        if isempty(fileName)
            uialert(app.UIFigure, 'Please enter a file name.', 'Error');
            return;
        end
        
        % Try loading the .mat file and plotting
        try
            % Load the .mat file
            data = load(fileName);
            
            % Ensure the file contains a 2D array
            fieldNames = fieldnames(data);
            if numel(fieldNames) ~= 1
                uialert(app.UIFigure, 'The .mat file must contain exactly one 2D array.', 'Error');
                return;
            end
            
            % Extract the 2D array
            array = data.(fieldNames{1});
            if size(array, 1) ~= 2
                uialert(app.UIFigure, 'The 2D array must have exactly two rows: one for X and one for Y.', 'Error');
                return;
            end
        catch
                % Display an error message if the file cannot be loaded
                msgbox(['Error loading the MAT file: ', fileName, '. Please check the filename or file path.']);
        end

            % Separate X and Y
            X=array(1,:);
            Y=array(2,:);
            
                % Get Z fn of X
                funcz=app.EnterZFunctionofXEditField.Value;
                algebraicFuncz=generateAlgebraicFunction(app,funcz,'X');
                Z=algebraicFuncz(X);
    
                % Get W fn of Y
                funcw=app.EnterWFunctionofYEditField.Value;
                algebraicFuncw = generateAlgebraicFunction(app,funcw,'Y'); % Convert the string to a symbolic expression
                W=algebraicFuncw(Y);

            %Mean
            mean_z=0;
            mean_w=0;
            for i=1 : length(Z)
                mean_z=mean_z+Z(i);
            end
            mean_z=mean_z/(length(Z))
            
            for i=1 : length(W)
                mean_w=mean_w+W(i);
            end
            mean_w=mean_w/length(W)

            app.Xmean.Text=['Mean of Z: ',num2str(mean_z,3)];
            app.Ymean.Text=['Mean of W: ',num2str(mean_w,3)];
            figure()
            [Z_pdf_counts,Z_pdf_edges]=histcounts(Z,'Normalization','pdf');
            subplot(2,1,1);
            PlotPDF(app,Z);
            title("Marginal Probability Distribution of Z");
            
            [W_pdf_counts,W_pdf_edges]=histcounts(W,'Normalization','pdf');
            subplot(2,1,2);
            PlotPDF(app,W);
            title("Marginal Probability Distribution of W");

            %STD
            std_z=0;
            std_w=0;
            for i=1:length(Z)
                std_z=std_z+(Z(i)-mean_z)^2;
            end
            std_z=std_z/length(Z);
            std_z=sqrt(std_z)
            
            for i=1:length(W)
                std_w=std_w+(W(i)-mean_w)^2;
            end
            std_w=std_w/length(W);
            std_w=sqrt(std_w)
            app.XStd.Text=['Standard Deviation of Z: ',num2str(std_z,3)];
            app.YStd.Text=['Standard Deviation of W: ',num2str(std_w,3)];
        end
    function algebraicfunc=generateAlgebraicFunction(app,funcstring,ch)
        try
            % Checking that format of function is correct
            if ~ischar(funcstring) && ~isstring(funcstring)
                    error('Input must be a string scalar or character vector.');
            end
            % If it is a string we turn it into a character vector so it is compatible with str2func
            if isstring(funcstring)
                    funcstring = char(funcstring);
            end
            % Ensure element-wise operations for arrays
            funcstring = strrep(funcstring, '^', '.^');
            funcstring = strrep(funcstring, '*', '.*'); 
            funcstring = strrep(funcstring, '/', './');
            algebraicfunc = str2func([['@(',char(ch),')'] funcstring]);
        catch 
            error('Invalid function string. Please ensure it is a valid MATLAB expression.');
        end
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: JointStatisticsButton
        function JointStatisticsButtonPushed(app, event)
            CalculateJointStatistics(app);
        end

        % Button pushed function: SingleStatisticsButton
        function SingleStatisticsButtonPushed(app, event)
            CalculateSingleStatistics(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterFileNameLabel = uilabel(app.UIFigure);
            app.EnterFileNameLabel.Text = 'Enter Filename';
            app.EnterFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.JointStatisticsButton = uibutton(app.UIFigure, 'push');
            app.JointStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @JointStatisticsButtonPushed, true);
            app.JointStatisticsButton.Text = 'Joint Statistics';
            app.Xmean = uilabel(app.UIFigure);
            app.Xmean.Text = 'Mean of Z';
            app.Ymean = uilabel(app.UIFigure);
            app.Ymean.Text = 'Mean of W';
            app.SingleStatisticsButton = uibutton(app.UIFigure, 'push');
            app.SingleStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @SingleStatisticsButtonPushed, true);
            app.SingleStatisticsButton.Text = 'Single Statistics';
            app.YStd = uilabel(app.UIFigure);
            app.YStd.Text = 'Std of W';
            app.XStd = uilabel(app.UIFigure);
            app.XStd.Text = 'Std of Z';
            app.Covar = uilabel(app.UIFigure);
            app.Covar.Text = 'Covariance';
            app.Corr = uilabel(app.UIFigure);
            app.Corr.Text = 'Correlation Coeffecient';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.FunctionsOfRandomVariableLabel = uilabel(app.UIFigure);
            app.FunctionsOfRandomVariableLabel.Text = 'Functions Of Random Variable';
            app.EnterZFunctionofXEditFieldLabel = uilabel(app.UIFigure);
            app.EnterZFunctionofXEditFieldLabel.Text = 'Enter Z Function of X';
            app.EnterZFunctionofXEditField = uieditfield(app.UIFigure, 'text');
            app.EnterWFunctionofYEditFieldLabel = uilabel(app.UIFigure);
            app.EnterWFunctionofYEditFieldLabel.Text = 'Enter W Function of Y';
            app.EnterWFunctionofYEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FnsOfRV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end