classdef JointApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        JointRandomVariableLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        Corr                      matlab.ui.control.Label
        Covar                     matlab.ui.control.Label
        XStd                      matlab.ui.control.Label
        YStd                      matlab.ui.control.Label
        SingleStatisticsButton    matlab.ui.control.Button
        Ymean                     matlab.ui.control.Label
        Xmean                     matlab.ui.control.Label
        JointStatisticsButton     matlab.ui.control.Button
        EnterFilenameEditField    matlab.ui.control.EditField
        EnterFileNameLabel        matlab.ui.control.Label
    end

    
    methods (Access = private)
    function PlotPDF(app,X)
        if isempty(X)
            msgbox('Invalid input for PDF plot');
            return;
        end
        unique_values=unique(X);
        if(size(unique_values)/size(X)<0.75)
            % Calculate PMF
             [pdf_counts, pdf_edges] = histcounts(X,'Binwidth',0.01,'Normalization', 'probability');
             pdf_x = pdf_edges(1:end-1); % PDF x values
    
                % Create a new figure for the PDF
             histogram('BinEdges',pdf_edges,'BinCounts',pdf_counts,'Normalization','probability');
             xlabel('Value');
             ylabel('Probability');
             title('Probability Mass Function (PMF)');
        end
        if(size(unique_values)/size(X)>0.75)
             % Calculate PDF
             [pdf_counts, pdf_edges] = histcounts(X,'Binwidth',0.01,'Normalization', 'pdf');
             pdf_x = pdf_edges(1:end-1); % PDF x values
    
             % Create a new figure for the PDF
             histogram('BinEdges',pdf_edges,'BinCounts',pdf_counts,'Normalization','pdf');
             xlabel('Value');
             ylabel('Probability');
             title('Probability Density Function (PDF)');
       end
    end
    
    % Button pushed function: PlotButton
    function CalculateJointStatistics(app)
        fileName = app.EnterFilenameEditField.Value;
        if isempty(fileName)
            uialert(app.UIFigure, 'Please enter a file name.', 'Error');
            return;
        end
        
        % Try loading the .mat file and plotting
        try
            % Load the .mat file
            data = load(fileName);
            
            % Ensure the file contains a 2D array
            fieldNames = fieldnames(data);
            if numel(fieldNames) ~= 1
                uialert(app.UIFigure, 'The .mat file must contain exactly one 2D array.', 'Error');
                return;
            end
            
            % Extract the 2D array
            array = data.(fieldNames{1});
            if size(array, 1) ~= 2
                uialert(app.UIFigure, 'The 2D array must have exactly two rows: one for X and one for Y.', 'Error');
                return;
            end

            % Separate X and Y
            X = array(1, :);
            Y = array(2, :);

            % Compute and plot the joint PDF
            [N_pdf, Xedges_pdf, Yedges_pdf] = histcounts2(X, Y, 'Normalization', 'pdf','BinWidth',0.1);
            figure;
            histogram2('XBinEdges', Xedges_pdf, 'YBinEdges', Yedges_pdf, 'BinCounts', N_pdf);
            title('Joint Probability Distribution (PDF)');
            xlabel('X');
            ylabel('Y');
            zlabel('PDF');
            
            %Mean
            mean_x=0;
            mean_y=0;
            XY=array;
            for i=1 : length(XY(1,:))
                mean_x=mean_x+XY(1,i);
            end
            mean_x=mean_x/(length(XY(1,:)))
            
            for i=1 : length(XY(2,:))
                mean_y=mean_y+XY(2,i);
            end
            mean_y=mean_y/length(XY(2,:))
            
            %Covariance
            covar=0;
            for i=1 : length(XY(1,:))
                cx=(XY(1,i)-mean_x);
                cy=(XY(2,i)-mean_y);
                covar=covar+cx*cy;
            end
            covar=covar/length(XY(1,:))
            
            %STD
            std_x=0;
            std_y=0;
            for i=1:length(XY(1,:))
                std_x=std_x+(XY(1,i)-mean_x)^2;
            end
            std_x=std_x/length(XY(1,:));
            std_x=sqrt(std_x)
            
            for i=1:length(XY(2,:))
                std_y=std_y+(XY(2,i)-mean_y)^2;
            end
            std_y=std_y/length(XY(2,:));
            std_y=sqrt(std_y)
            
            %Correlation
            correlation_coeff=covar/(std_x*std_y)
            app.Covar.Text=['Covariance: ',num2str(covar,3)];
            app.Corr.Text=['Correlation Coeffecient: ',num2str(correlation_coeff,3)];
        catch ME
            % Show an error dialog if anything goes wrong
            uialert(app.UIFigure, ['Error: ' ME.message], 'Error');
        end
    end
       
    function CalculateSingleStatistics(app)
        % Get file name from the edit field
        fileName = app.EnterFilenameEditField.Value;
         
        % Validate the file name
        if isempty(fileName)
            uialert(app.UIFigure, 'Please enter a file name.', 'Error');
            return;
        end
        
        % Try loading the .mat file and plotting
        try
            % Load the .mat file
            data = load(fileName);
            
            % Ensure the file contains a 2D array
            fieldNames = fieldnames(data);
            if numel(fieldNames) ~= 1
                uialert(app.UIFigure, 'The .mat file must contain exactly one 2D array.', 'Error');
                return;
            end
            
            % Extract the 2D array
            array = data.(fieldNames{1});
            if size(array, 1) ~= 2
                uialert(app.UIFigure, 'The 2D array must have exactly two rows: one for X and one for Y.', 'Error');
                return;
            end
        catch
                % Display an error message if the file cannot be loaded
                msgbox(['Error loading the MAT file: ', fileName, '. Please check the filename or file path.']);
        end

            % Separate X and Y
            XY=array;
            X=array(1,:);
            Y=array(2,:);
            %Mean
            mean_x=0;
            mean_y=0;
            for i=1 : length(XY(1,:))
                mean_x=mean_x+XY(1,i);
            end
            mean_x=mean_x/(length(XY(1,:)))
            
            for i=1 : length(XY(2,:))
                mean_y=mean_y+XY(2,i);
            end
            mean_y=mean_y/length(XY(2,:))

            app.Xmean.Text=['Mean of X: ',num2str(mean_x,3)];
            app.Ymean.Text=['Mean of Y: ',num2str(mean_y,3)];
            figure()
            subplot(2,1,1);
            PlotPDF(app,X)
            title("Marginal Probability Distribution of X");
            subplot(2,1,2);
            PlotPDF(app,Y)
            title("Marginal Probability Distribution of Y");

            %STD
            std_x=0;
            std_y=0;
            for i=1:length(XY(1,:))
                std_x=std_x+(XY(1,i)-mean_x)^2;
            end
            std_x=std_x/length(XY(1,:));
            std_x=sqrt(std_x)
            
            for i=1:length(XY(2,:))
                std_y=std_y+(XY(2,i)-mean_y)^2;
            end
            std_y=std_y/length(XY(2,:));
            std_y=sqrt(std_y)
            app.XStd.Text=['Standard Deviation of X: ',num2str(std_x,3)];
            app.YStd.Text=['Standard Deviation of Y: ',num2str(std_y,3)];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: JointStatisticsButton
        function JointStatisticsButtonPushed(app, event)
            CalculateJointStatistics(app);
        end

        % Button pushed function: SingleStatisticsButton
        function SingleStatisticsButtonPushed(app, event)
            CalculateSingleStatistics(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterFileNameLabel = uilabel(app.UIFigure);
            app.EnterFileNameLabel.Text = 'Enter Filename';
            app.EnterFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.JointStatisticsButton = uibutton(app.UIFigure, 'push');
            app.JointStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @JointStatisticsButtonPushed, true);
            app.JointStatisticsButton.Text = 'Joint Statistics';
            app.Xmean = uilabel(app.UIFigure);
            app.Xmean.Text = 'Mean of X';
            app.Ymean = uilabel(app.UIFigure);
            app.Ymean.Text = 'Mean of Y';
            app.SingleStatisticsButton = uibutton(app.UIFigure, 'push');
            app.SingleStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @SingleStatisticsButtonPushed, true);
            app.SingleStatisticsButton.Text = 'Single Statistics';
            app.YStd = uilabel(app.UIFigure);
            app.YStd.Text = 'Std of Y';
            app.XStd = uilabel(app.UIFigure);
            app.XStd.Text = 'Std of X';
            app.Covar = uilabel(app.UIFigure);
            app.Covar.Text = 'Covariance';
            app.Corr = uilabel(app.UIFigure);
            app.Corr.Text = 'Correlation Coeffecient';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.JointRandomVariableLabel = uilabel(app.UIFigure);
            app.JointRandomVariableLabel.Text = 'Joint Random Variable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JointApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end