classdef SingleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MGF0Label_3                matlab.ui.control.Label
        MGF0Label_2                matlab.ui.control.Label
        MGF0Label                  matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        EndValueofTEditField       matlab.ui.control.NumericEditField
        EndValueofTEditFieldLabel  matlab.ui.control.Label
        SingleRandomVariableLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        ViewMGFButton              matlab.ui.control.Button
        EnterFilenameEditField     matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        ViewPDFButton              matlab.ui.control.Button
        ViewCDFButton              matlab.ui.control.Button
        ThirdmomentLabel           matlab.ui.control.Label
        VarianceLabel              matlab.ui.control.Label
        MeanLabel                  matlab.ui.control.Label
        CaculateStatisticsButton   matlab.ui.control.Button
    end


    methods (Access = private)
  
        function meanX = calculateMean(app, X)
            sumX = sum(X);  % Use built-in sum function
            count = length(X);
            meanX = sumX / count;
        end

        function varX = calculateVariance(app, X, meanX)
            sumX = sum((X - meanX).^2);  % Use vectorized operation
            count = length(X);
            varX = sumX / count;
        end

        function moment3 = calculateThirdMoment(app, X, meanX)
            moment3 = sum((X - meanX).^3) / length(X);
        end

   function calculateStatistics(app)
            % Get input from the EditField (filename or full path)
            fileName = app.EnterFilenameEditField.Value;
          
            if ~endsWith(fileName, '.mat')
                fileName = [fileName, '.mat'];
            end
            
            try
                fileData = load(fileName);  % Load the MAT file
                
                
                if isfield(fileData, 'data')
                    X = fileData.data;  
                    
                    meanX = app.calculateMean(X);
                    varX = app.calculateVariance(X, meanX);
                    moment3 = app.calculateThirdMoment(X, meanX);
                
                    app.MeanLabel.Text = ['Mean: ', num2str(meanX,3)];
                    app.VarianceLabel.Text = ['Variance: ', num2str(varX,3)];
                    app.ThirdmomentLabel.Text = ['Third Moment: ', num2str(moment3,3)];
                else
                    msgbox('The MAT file does not contain the expected variable "data".');
                end
            catch
                msgbox(['Error loading the MAT file: ', fileName, '. Please check the filename or file path.']);
            end
        end

        function plotCDF(app)
             fileName = app.EnterFilenameEditField.Value;
             if ~endsWith(fileName, '.mat')
                fileName = [fileName, '.mat'];
            end
            fileData = load(fileName);
              X = fileData.data;
          
            
            if isempty(X)
                msgbox('Invalid input for CDF plot');
                return;
            end

            [cdf_counts, cdf_edges] = histcounts(X, 'Normalization', 'cdf','Binwidth',0.1);
            figure;
            histogram('BinEdges',cdf_edges,'BinCounts',cdf_counts);
            xlabel('Value');
            ylabel('Cumulative Probability');
            title('Cumulative Distribution Function (CDF)');
            grid on;
        end

        function plotPDF(app)
            fileName = app.EnterFilenameEditField.Value;
            if ~endsWith(fileName, '.mat')
                fileName = [fileName, '.mat'];
            end
            fileData = load(fileName);
            X = fileData.data;
            
            if isempty(X)
                msgbox('Invalid input for PDF plot');
                return;
            end

            unique_values=unique(X);
            if(size(unique_values)/size(X)<0.75)
                [pdf_counts, pdf_edges] = histcounts(X,'Binwidth',0.01,'Normalization', 'probability');
    
                figure;
                histogram('BinEdges',pdf_edges,'BinCounts',pdf_counts,'Normalization','probability');
                xlabel('Value');
                ylabel('Probability');
                title('Probability Mass Function (PMF)');
            end
            if(size(unique_values)/size(X)>0.75)

                [pdf_counts, pdf_edges] = histcounts(X,'Normalization', 'pdf');

                figure;
                histogram('BinEdges',pdf_edges,'BinCounts',pdf_counts,'Normalization','pdf');
                xlabel('Value');
                ylabel('Probability');
                title('Probability Density Function (PDF)');
            end

            grid on;
        end

        function mgf = calculateMGF(app)
            syms t;
            fileName = app.EnterFilenameEditField.Value;
            if ~endsWith(fileName, '.mat')
                fileName = [fileName, '.mat'];
            end
            fileData = load(fileName);
            X = fileData.data;
            mgf = 0;  
            [pdf_counts, pdf_edges] = histcounts(X, 'Normalization', 'pdf','Binwidth',0.1);
            bin_widths = diff(pdf_edges);
            for j = 1:length(pdf_counts)
                center = (pdf_edges(j) + pdf_edges(j + 1)) / 2;  
                mgf = mgf + bin_widths(j)* pdf_counts(j) * exp(center * t); 
            end
            t_end=app.EndValueofTEditField.Value;
            syms m1(t);
            m1=diff(mgf,t);
            syms m2(t);
            m2=diff(m1,t);
            em=simplify(subs(mgf,t,0));
            em=double(round(em,3));
            em1=simplify(subs(m1,t,0));
            em1=double(round(em1,3));
            em2=simplify(subs(m2,t,0));
            em2=double(round(em2,3));
            app.MGF0Label.Text=['MGF(0): ',num2str(em,3)];
            app.MGF0Label_2.Text=['DMGF(0): ',num2str(em1,3)];
            app.MGF0Label_3.Text=['D2MGF(0): ',num2str(em2,3)];
            figure()
            subplot(3,1,1);
            fplot(mgf,[0,t_end]);
            title("MGF");
            subplot(3,1,2);
            fplot(m1,[0,t_end])
            title("1st Derivative of MGF")
            
            subplot(3,1,3);
            fplot(m2,[0,t_end]);
            title("2nd Derivative of MGF");
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CaculateStatisticsButton
        function CaculateStatisticsButtonPushed(app, event)
            calculateStatistics(app);
        end

        % Button pushed function: ViewCDFButton
        function ViewCDFButtonPushed(app, event)
            plotCDF(app);
        end

        % Button pushed function: ViewPDFButton
        function ViewPDFButtonPushed(app, event)
            plotPDF(app);
        end

        % Button pushed function: ViewMGFButton
        function ViewMGFButtonPushed(app, event)
            calculateMGF(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CaculateStatisticsButton = uibutton(app.UIFigure, 'push');
            app.CaculateStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @CaculateStatisticsButtonPushed, true);
            app.CaculateStatisticsButton.Text = 'Caculate Statistics';
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'Mean';
            app.VarianceLabel = uilabel(app.UIFigure);
            app.VarianceLabel.Text = 'Variance';
            app.ThirdmomentLabel = uilabel(app.UIFigure);
            app.ThirdmomentLabel.Text = 'Third moment';
            app.ViewCDFButton = uibutton(app.UIFigure, 'push');
            app.ViewCDFButton.ButtonPushedFcn = createCallbackFcn(app, @ViewCDFButtonPushed, true);
            app.ViewCDFButton.Text = 'View CDF';
            app.ViewPDFButton = uibutton(app.UIFigure, 'push');
            app.ViewPDFButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPDFButtonPushed, true);
            app.ViewPDFButton.Text = 'View PDF';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Enter Filename';
            app.EnterFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.ViewMGFButton = uibutton(app.UIFigure, 'push');
            app.ViewMGFButton.ButtonPushedFcn = createCallbackFcn(app, @ViewMGFButtonPushed, true);
            app.ViewMGFButton.Text = 'View MGF';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.SingleRandomVariableLabel = uilabel(app.UIFigure);
            app.SingleRandomVariableLabel.Text = 'Single Random Variable';
            app.EndValueofTEditFieldLabel = uilabel(app.UIFigure);
            app.EndValueofTEditFieldLabel.Text = 'End Value of T';
            app.EndValueofTEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndValueofTEditField.Value = 0.02;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.MGF0Label = uilabel(app.UIFigure);
            app.MGF0Label.Text = 'MGF(0)';
            app.MGF0Label_2 = uilabel(app.UIFigure);
            app.MGF0Label_2.Text = 'MGF''(0)';
            app.MGF0Label_3 = uilabel(app.UIFigure);
            app.MGF0Label_3.Text = 'MGF''''(0)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end